classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Button                   matlab.ui.control.Button
        Mu1EditFieldLabel        matlab.ui.control.Label
        Mu1EditField             matlab.ui.control.NumericEditField
        Mu2EditFieldLabel        matlab.ui.control.Label
        Mu2EditField             matlab.ui.control.NumericEditField
        UIAxes                   matlab.ui.control.UIAxes
        Sigma1EditFieldLabel     matlab.ui.control.Label
        Sigma1EditField          matlab.ui.control.NumericEditField
        Sigma2EditFieldLabel     matlab.ui.control.Label
        Sigma2EditField          matlab.ui.control.NumericEditField
        MixEditFieldLabel        matlab.ui.control.Label
        MixEditField             matlab.ui.control.NumericEditField
        IterationEditFieldLabel  matlab.ui.control.Label
        IterationEditField       matlab.ui.control.NumericEditField
        MixEditField_2Label      matlab.ui.control.Label
        MixEditField_2           matlab.ui.control.NumericEditField
    end


    methods (Access = public)
    
        function results = func(app,a,b)
            results = a*b;
        end
        
    end

    methods (Access = private)
    
        function results = func2(app,a,b)
            results = a/b;
        end
        
        function results = gmm_calc(app, Mu1, Mu2, sigma1, sigma2, mix, num_steps, x, y)
            %initialize parameters
            Mu = [Mu1 Mu2];
            sigma = [sigma1 sigma2];
            pi = mix;
            wp1 = zeros(1, length(y)) ;
            wp2 = zeros(1, length(y)) ;
            for iteration=1:num_steps
                %Expectation Step
                %for each of the point, wich Gaussian generated it

                wp1 = pdf('Normal',y,Mu(1),sigma(1))*pi;
                wp2 = pdf('Normal',y,Mu(2),sigma(2))*(1-pi);
                dem = wp1+wp2;
                wp1 = wp1./dem;
                wp2 = wp2./dem;
                    
                %Maximization Step
                %Modify the hidden value such that is maximizes the probability
                %calc new Mu(1) 
                Mu(1)=sum(wp1.*y)/sum(wp1);
                %calc new Mu(2)
                Mu(2)=sum(wp2.*y)/sum(wp2);
                %calc new sigma(1)
                sigma(1)=sqrt(sum(wp1.*(y-Mu(1)).^2)/sum(wp1));
                %calc new sigma(2)
                sigma(2)=sqrt(sum(wp2.*(y-Mu(2)).^2)/sum(wp2));
                %calc new pi:
                pi=sum(wp2)/length(y);
                %Is Converged
            
            end
            results = [Mu(1) Mu(2) sigma(1) sigma(2) pi];    
        end
        
    end


    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)

            %var = func2(app,app.Mu1EditField.Value,app.Mu2EditField.Value);
            y_1 = normrnd(22,2,[1,20]);
            y_2 = normrnd(12,2,[1,20]);
            y = [y_1,y_2];
            x = -1:30;
            ret_gmm = gmm_calc(app, app.Mu1EditField.Value,app.Mu2EditField.Value,app.Sigma1EditField.Value,app.Sigma2EditField.Value,app.MixEditField.Value,app.IterationEditField.Value,x,y);
            app.MixEditField_2.Value = ret_gmm(5);
            
            
            plot(app.UIAxes, pdf('Normal',x,ret_gmm(1),ret_gmm(3)), '-r', 'LineWidth',2);      
            hold(app.UIAxes);
            plot(app.UIAxes, pdf('Normal',x,ret_gmm(2),ret_gmm(4)), '-g', 'LineWidth',2)
            plot(app.UIAxes, pdf('Normal',x,app.Mu1EditField.Value,app.Sigma1EditField.Value), '--r', 'LineWidth',2)
            plot(app.UIAxes, pdf('Normal',x,app.Mu2EditField.Value,app.Sigma2EditField.Value), '--g', 'LineWidth',2) 
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Mu1EditFieldLabel = uilabel(app.UIFigure);
            app.Mu1EditFieldLabel.Text = 'Mu 1';
            app.Mu1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Mu1EditField.Value = 5;
            app.Mu2EditFieldLabel = uilabel(app.UIFigure);
            app.Mu2EditFieldLabel.Text = 'Mu 2';
            app.Mu2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Mu2EditField.Value = 15;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X-Axis');
            ylabel(app.UIAxes, 'Y-Axis');
            app.Sigma1EditFieldLabel = uilabel(app.UIFigure);
            app.Sigma1EditFieldLabel.Text = 'Sigma 1';
            app.Sigma1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Sigma1EditField.Value = 5;
            app.Sigma2EditFieldLabel = uilabel(app.UIFigure);
            app.Sigma2EditFieldLabel.Text = 'Sigma 2';
            app.Sigma2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Sigma2EditField.Value = 5;
            app.MixEditFieldLabel = uilabel(app.UIFigure);
            app.MixEditFieldLabel.Text = 'Mix';
            app.MixEditField = uieditfield(app.UIFigure, 'numeric');
            app.MixEditField.Limits = [0 1];
            app.MixEditField.Value = 0.2;
            app.IterationEditFieldLabel = uilabel(app.UIFigure);
            app.IterationEditFieldLabel.Text = 'Iteration';
            app.IterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationEditField.Limits = [0 Inf];
            app.IterationEditField.Value = 100;
            app.MixEditField_2Label = uilabel(app.UIFigure);
            app.MixEditField_2Label.Text = 'Mix';
            app.MixEditField_2 = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end